classdef app1TPFINALED3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        cambiarmodo            matlab.ui.Figure
        CONECTARButton         matlab.ui.control.Button
        DESCONECTARButton      matlab.ui.control.Button
        MONITOREditFieldLabel  matlab.ui.control.Label
        monitor                matlab.ui.control.EditField
        ConexionLampLabel      matlab.ui.control.Label
        ConexionLamp           matlab.ui.control.Lamp
        DesconexionLampLabel   matlab.ui.control.Label
        DesconexionLamp        matlab.ui.control.Lamp
        SALIRButton            matlab.ui.control.Button
        PuertodispLabel        matlab.ui.control.Label
        ModoactualButtonGroup  matlab.ui.container.ButtonGroup
        TemperaturaButton      matlab.ui.control.RadioButton
        HumedadButton          matlab.ui.control.RadioButton
        NingunoButton          matlab.ui.control.RadioButton
        AmbosButton            matlab.ui.control.RadioButton
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        CAMBIARMODOLabel       matlab.ui.control.Label
        changemodo             matlab.ui.control.NumericEditField
        sendUART               matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        SeMaKuLabel            matlab.ui.control.Label
        version10Label         matlab.ui.control.Label
        graficaHum             matlab.ui.control.UIAxes
        graficaTem             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        varglobal % para que no se repita la configuracion inicial
        dataH % datos de humedad
        dataT % datos de temperatura
        corte % para cortar el while (al desconectar)
        device % guarda el puerto seriañ
        t % temperatura. sirve para graficar
        h % humedad. sirve para graficar
        a % para opcion ambos
        decision % para elegir cual graficar
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.varglobal = 0 ;
           app.PuertodispLabel.Text =serialportlist("available");
           app.corte = 0;
           app.a=1;
           %addpath('C:\Users\Gaston\Downloads\imgmatlab');
        end

        % Button pushed function: CONECTARButton
        function CONECTARButtonPushed(app, event)

        app.varglobal = app.varglobal + 1;
        app.corte = 1;
        
        app.ConexionLamp.Color= 'green';
        app.DesconexionLamp.Color= 'white'; 
            
        if(app.varglobal==1) % configurar
            
            app.device=serialport("COM3",9600);
            configureTerminator(app.device,"LF");%va a leer hasta que llegue:CR/LF (osea un final de linea y un enter)
            app.t=1;
            app.h=1;
            app.a=1;
            app.decision=1;
            
        end 
      
        
        while(app.corte)
           
            app.monitor.Value=(readline(app.device));% guarda el valor de monitor
            
            %-------- modos
            
            if startsWith(app.monitor.Value,'H') %humedad
                if (app.decision~=5) % si no entro primero la opcion ambas
                   app.decision=2;
                  
                end
                continue;
            end
            if startsWith(app.monitor.Value,'T') % temperatura
                if (app.decision~=5) % si no entro primero la opcion ambas
                   app.decision=3;
                   
                end           
                continue;
            end
            if startsWith(app.monitor.Value,'N') % ningun grafico
                app.decision=4;
                
                continue;
            end
            if startsWith(app.monitor.Value,'A') % ambos graficos
                app.decision=5;
                app.a=1;
                
                app.AmbosButton.Value = 1;
                app.NingunoButton.Value = 0;
                app.HumedadButton.Value = 0; 
                app.TemperaturaButton.Value = 0;  
                continue;
            end
            
            if startsWith(app.monitor.Value,'G')
                app.Image.Visible = 'on';
                app.Image2.Visible = 'off';
                continue;
            end
            
            if startsWith(app.monitor.Value,'W')
                app.Image.Visible = 'off';
                app.Image2.Visible = 'on';
                continue;
            end
            
            %-------------------- tipos de graficos segun modo
            
            if((app.decision == 5) && (app.a==1)) % ambos graficos. primero humedad
                app.dataH(app.h)=str2double(app.monitor.Value);%con readline leo lo q me mandan por serial y lo convertimos de string a double
                plot(app.graficaHum,app.dataH); % graficamos los datos q llegan
                app.h=app.h+1; % se va guardando en data los datos leidos
                pause(0.01); 
                app.a=2;
                app.Image.Visible = 'off';
                app.Image2.Visible = 'off';
                continue;
            end
            
            if ((app.decision == 5)) % ambos graficos. ahora temperatura
                app.dataT(app.t)=str2double(app.monitor.Value);%con readline leo lo q me mandan por serial y lo convertimos de string a double
                plot(app.graficaTem,app.dataT); % graficamos los datos q llegan
                app.t=app.t+1; % se va guardando en data los datos leidos
                pause(0.01); 
                app.decision=1;
                app.Image.Visible = 'off';
                app.Image2.Visible = 'off';
                continue;
            end

            if((app.decision == 2))  % humedad
                if (((app.HumedadButton.Value) == 0 )) 
                        app.HumedadButton.Value = 1;
                        app.TemperaturaButton.Value = 0;
                        app.AmbosButton.Value = 0;
                        app.NingunoButton.Value = 0;
                end
                app.dataH(app.h)=str2double(app.monitor.Value);%con readline leo lo q me mandan por serial y lo convertimos de string a double
                plot(app.graficaHum,app.dataH); % graficamos los datos q llegan
                app.h=app.h+1; % se va guardando en data los datos leidos
                pause(0.01); 
                app.decision = 1;
                app.Image.Visible = 'off';
                app.Image2.Visible = 'off';
            end
            
            if(app.decision == 3) % temperatura
                if ((app.TemperaturaButton.Value == 0))
                        app.TemperaturaButton.Value = 1;
                        app.HumedadButton.Value = 0;
                        app.AmbosButton.Value = 0; 
                        app.NingunoButton.Value = 0;
                end
                app.dataT(app.t)=str2double(app.monitor.Value);%con readline leo lo q me mandan por serial y lo convertimos de string a double  
                plot(app.graficaTem,app.dataT); % graficamos los datos q llegan
                app.t=app.t+1; % se va guardando en data los datos leidos
                pause(0.01); 
                app.decision = 1;
                app.Image.Visible = 'off';
                app.Image2.Visible = 'off';
            end
            
            if(app.decision == 4) % ningun grafico
                if (app.NingunoButton.Value == 0)
                        app.NingunoButton.Value = 1;
                        app.HumedadButton.Value = 0; 
                        app.TemperaturaButton.Value = 0; 
                        app.AmbosButton.Value = 0; 
                        
                end
                app.decision = 1;
                app.Image.Visible = 'off';
                app.Image2.Visible = 'off';
            end
        end
        end

        % Button pushed function: DESCONECTARButton
        function DESCONECTARButtonPushed(app, event)
            app.ConexionLamp.Color= 'white'; % cambiamos color de leds de la grafica
            app.DesconexionLamp.Color= 'red'; % cambiamos color de leds de la grafica
            
            app.corte = 0;
            
        end

        % Button pushed function: SALIRButton
        function SALIRButtonPushed(app, event)
            app.delete; %se sale la aplicacion al pulsar el boton
        end

        % Selection changed function: ModoactualButtonGroup
        function ModoactualButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModoactualButtonGroup.SelectedObject;
            
        end

        % Button pushed function: sendUART
        function sendUARTButtonPushed(app, event)
            write(app.device,num2str(app.changemodo.Value), "char"); % enviamos a la placa lo que se escribio en changemodo
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.cambiarmodo = uifigure('Visible', 'off');
            app.cambiarmodo.Name = 'MATLAB App';
            app.CONECTARButton = uibutton(app.cambiarmodo, 'push');
            app.CONECTARButton.ButtonPushedFcn = createCallbackFcn(app, @CONECTARButtonPushed, true);
            app.CONECTARButton.Text = 'CONECTAR';
            app.DESCONECTARButton = uibutton(app.cambiarmodo, 'push');
            app.DESCONECTARButton.ButtonPushedFcn = createCallbackFcn(app, @DESCONECTARButtonPushed, true);
            app.DESCONECTARButton.Text = 'DESCONECTAR';
            app.MONITOREditFieldLabel = uilabel(app.cambiarmodo);
            app.MONITOREditFieldLabel.Text = 'MONITOR';
            app.monitor = uieditfield(app.cambiarmodo, 'text');
            app.ConexionLampLabel = uilabel(app.cambiarmodo);
            app.ConexionLampLabel.Text = 'Conexion';
            app.ConexionLamp = uilamp(app.cambiarmodo);
            app.DesconexionLampLabel = uilabel(app.cambiarmodo);
            app.DesconexionLampLabel.Text = 'Desconexion';
            app.DesconexionLamp = uilamp(app.cambiarmodo);
            app.SALIRButton = uibutton(app.cambiarmodo, 'push');
            app.SALIRButton.ButtonPushedFcn = createCallbackFcn(app, @SALIRButtonPushed, true);
            app.SALIRButton.Text = 'SALIR';
            app.PuertodispLabel = uilabel(app.cambiarmodo);
            app.PuertodispLabel.Text = {'Puerto disp'; ''};
            app.ModoactualButtonGroup = uibuttongroup(app.cambiarmodo);
            app.ModoactualButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModoactualButtonGroupSelectionChanged, true);
            app.ModoactualButtonGroup.Title = 'Modo actual:';
            app.TemperaturaButton = uiradiobutton(app.ModoactualButtonGroup);
            app.TemperaturaButton.Text = 'Temperatura';
            app.HumedadButton = uiradiobutton(app.ModoactualButtonGroup);
            app.HumedadButton.Text = 'Humedad';
            app.NingunoButton = uiradiobutton(app.ModoactualButtonGroup);
            app.NingunoButton.Text = 'Ninguno';
            app.NingunoButton.Value = true;
            app.AmbosButton = uiradiobutton(app.ModoactualButtonGroup);
            app.AmbosButton.Text = 'Ambos';
            app.Label = uilabel(app.ModoactualButtonGroup);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.ModoactualButtonGroup);
            app.Label_2.Text = '1';
            app.Label_3 = uilabel(app.ModoactualButtonGroup);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.ModoactualButtonGroup);
            app.Label_4.Text = '3';
            app.CAMBIARMODOLabel = uilabel(app.cambiarmodo);
            app.CAMBIARMODOLabel.Text = 'CAMBIAR MODO:';
            app.changemodo = uieditfield(app.cambiarmodo, 'numeric');
            app.changemodo.Limits = [0 3];
            app.sendUART = uibutton(app.cambiarmodo, 'push');
            app.sendUART.ButtonPushedFcn = createCallbackFcn(app, @sendUARTButtonPushed, true);
            app.sendUART.Text = 'Enviar';
            app.Image = uiimage(app.cambiarmodo);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'imagenTilde.png';
            app.Image2 = uiimage(app.cambiarmodo);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = 'imagenWarning.png';
            app.SeMaKuLabel = uilabel(app.cambiarmodo);
            app.SeMaKuLabel.Text = 'SeMaKu';
            app.version10Label = uilabel(app.cambiarmodo);
            app.version10Label.Text = 'version 1.0';
            app.graficaHum = uiaxes(app.cambiarmodo);
            title(app.graficaHum, 'GRAFICO HUMEDAD')
            xlabel(app.graficaHum, 'Tiempo')
            ylabel(app.graficaHum, 'Humedad')
            zlabel(app.graficaHum, 'Z')
            app.graficaTem = uiaxes(app.cambiarmodo);
            title(app.graficaTem, 'GRAFICO TEMPERATURA')
            xlabel(app.graficaTem, 'Tiempo')
            ylabel(app.graficaTem, 'Temperatura')
            zlabel(app.graficaTem, 'Z')
            % Show the figure after all components are created
            app.cambiarmodo.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1TPFINALED3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.cambiarmodo)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.cambiarmodo)
        end
    end
end